classdef simpleDisplay < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FunctionsMenu                matlab.ui.container.Menu
        RuntestsonmodelsMenu         matlab.ui.container.Menu
        RejectblinksMenu             matlab.ui.container.Menu
        MultipledataMenu             matlab.ui.container.Menu
        LoadMenu                     matlab.ui.container.Menu
        LoadfromALLEEGMenu           matlab.ui.container.Menu
        LoadfromcsvfileMenu          matlab.ui.container.Menu
        LoadsavedmissionMenu         matlab.ui.container.Menu
        SaveMenu                     matlab.ui.container.Menu
        SavelocationsMenu            matlab.ui.container.Menu
        SavecurrentmissionMenu       matlab.ui.container.Menu
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        DirectindexSwitch            matlab.ui.control.Switch
        DirectindexSwitchLabel       matlab.ui.control.Label
        RegionplotSwitch             matlab.ui.control.Switch
        RegionplotSwitchLabel        matlab.ui.control.Label
        DisplayChannelDropDown       matlab.ui.control.DropDown
        DisplayChannelDropDownLabel  matlab.ui.control.Label
        DoubleVerificationListBox    matlab.ui.control.ListBox
        DoubleVerificationLabel      matlab.ui.control.Label
        ModelListDropDown            matlab.ui.control.DropDown
        ModelListDropDownLabel       matlab.ui.control.Label
        StartDetectionButton         matlab.ui.control.Button
        SamplingRateperseccondEditField  matlab.ui.control.NumericEditField
        SamplingRateperseccondLabel  matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        
        signals = table(Size=[10 3], VariableTypes={'double','double','double'});
        samplingRate = 1;
        modelName = '1';
        options = detectOptions();
        detector = blinkDetector();
        blinkLocate = [];
        dispCh = 1;
        showRegion = 0;
        showIndex = 0;
        testSignals = table(Size=[10 3], VariableTypes={'double','double','double'});
        testResult = {};
        xLimitListener event.listener
        % xlimit = [0 1];

    end
    
    methods (Access = private)

        function xLimChanged(app, ~, ~)
            app.xlimit = app.UIAxes.XLim;
        end
        
        function result = XTickToSeccond(app)
            app.showIndex = app.DirectindexSwitch.Value;
            if strcmp(app.showIndex,'On')
                app.UIAxes.XTick = round(app.UIAxes.XLim(1):diff(app.UIAxes.XLim)/10:app.UIAxes.XLim(2));
                app.UIAxes.XTickLabel = string(app.UIAxes.XTick);
            else
                app.UIAxes.XTick = (app.UIAxes.XLim(1):diff(app.UIAxes.XLim)/10:app.UIAxes.XLim(2));
                app.UIAxes.XTickLabel = string(app.UIAxes.XTick/app.samplingRate) + 's';
            end
        end
        
        function updateXLim(app, ~, event)
            app.UIAxes.XLim = event.Axes.XLim;
            app.XTickToSeccond();
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, blinkDetectorObject, ALLEEG)
            warning('off', 'all'); % ui bug but still work
            
            app.signals = blinkDetectorObject.signals;
            app.samplingRate = blinkDetectorObject.samplingRate;
            app.modelName = blinkDetectorObject.modelName;
            app.options = blinkDetectorObject.options;
            
            app.detector = blinkDetectorObject();
            
            plot(app.UIAxes,app.signals{:,1});
            app.xLimitListener = addlistener(app.UIAxes, 'XLim', 'PostSet', @(src, event) app.updateXLim(src, event));
            % Setup listener for zoom
            z = zoom(app.UIFigure);
            z.ActionPostCallback = @(src, event) app.updateXLim(src, event);
        
            % Setup listener for pan
            p = pan(app.UIFigure);
            p.ActionPostCallback = @(src, event) app.updateXLim(src, event);

            app.XTickToSeccond();
            title(app.UIAxes,'dataset name')
            app.SamplingRateperseccondEditField.Value = app.samplingRate;
            app.Label.Text = 'dataset name';
            % app.DisplayChannelDropDown.Items = num2cell(1:size(app.signals,2));
            % app.DisplayChannelDropDown.Value = app.DisplayChannelDropDown.Items{1};

            %% trail test
            choice = questdlg('Do you want to run a test to see which model fits better?', ...
                'Run Trial', ...
                'Yes', 'No', 'Yes');
            
            switch choice
                case 'Yes'
                    
                    if size(app.signals,1) >= 102400/2
                        app.testSignals = app.signals{1:10240/2,:};
                    else
                        app.testSignals = app.signals;
                    end
                    for name = app.ModelListDropDown.Items
                        name = name{1}(2:end-1);
                        h = waitbar(0, ['Testing model: ' name]);
                        testDetector = blinkDetector(app.testSignals, app.samplingRate, name, app.options);

                        app.testResult{end+1} = testDetector.detectBlinks(h);

                        close(h);

                    end
                case 'No'

            end
            

        end

        % Button pushed function: StartDetectionButton
        function StartDetectionButtonPushed(app, event)
            
            h = waitbar(0, 'Please wait...');    % progress bar

            app.detector.options = app.options;
            outNum = 1;
            if app.showRegion
                app.detector.options.output = [app.detector.options.output,'mask'];
                outNum = outNum+1;
            end
            [varargout{1:outNum}] = app.detector.detectBlinks(h);
            blinkLocations = varargout{1};
            app.blinkLocate = blinkLocations;
            if app.showRegion
                mask = varargout{2};
            end

            stChannel = app.signals{:,app.dispCh};
            plot(app.UIAxes,stChannel);

            coordinate = stChannel(blinkLocations);

            app.XTickToSeccond();

            hold(app.UIAxes,'on');
            if strcmp(app.showRegion,'On')
                stem(app.UIAxes,find(mask == "opening"),stChannel(mask == "opening"), Color='g', LineWidth=1, MarkerSize=1);
                stem(app.UIAxes,find(mask == "closing"),stChannel(mask == "closing"), Color='yellow',LineWidth=1, MarkerSize=1);
                legend(app.UIAxes,["closing","opening","signal","blink"]);
            end

            plot(app.UIAxes,1:size(stChannel),stChannel','Color',[0 0.447 0.741]);
            plot(app.UIAxes,blinkLocations,coordinate,'r*');
            hold(app.UIAxes,'off');
                  
            close(h);% close progress bar  
            try
            catch
            end
        end

        % Value changed function: DoubleVerificationListBox
        function DoubleVerificationListBoxValueChanged(app, event)

            app.options.shiftFactor = app.DoubleVerificationListBox.Value;
        end

        % Value changed function: SamplingRateperseccondEditField
        function SamplingRateperseccondEditFieldValueChanged(app, event)
            app.samplingRate = app.SamplingRateperseccondEditField.Value;
            
        end

        % Value changed function: ModelListDropDown
        function ModelListDropDownValueChanged(app, event)
            app.modelName = app.ModelListDropDown.Value;
            if ~isempty(app.testResult)
                stChannel = app.testSignals(:,app.dispCh);
                plot(app.UIAxes,stChannel);
                blinkLocations = app.testResult{find(strcmp(app.ModelListDropDown.Items, app.modelName),1)};
                coordinate = stChannel(blinkLocations);
    
                app.XTickToSeccond();
    
                hold(app.UIAxes,'on');
    
                plot(app.UIAxes,1:size(stChannel),stChannel','Color',[0 0.447 0.741]);
                plot(app.UIAxes,blinkLocations,coordinate,'r*');
                hold(app.UIAxes,'off');
            end
        end

        % Value changed function: DisplayChannelDropDown
        function DisplayChannelDropDownValueChanged(app, event)
            app.dispCh = app.DisplayChannelDropDown.Value;
            
        end

        % Value changed function: RegionplotSwitch
        function RegionplotSwitchValueChanged(app, event)
            app.showRegion = app.RegionplotSwitch.Value;
            
        end

        % Value changed function: DirectindexSwitch
        function DirectindexSwitchValueChanged(app, event)
            app.showIndex = app.DirectindexSwitch.Value;
            app.XTickToSeccond();
        end

        % Menu selected function: SavelocationsMenu
        function SavelocationsMenuSelected(app, event)
            assignin('base', 'BLINKLOCATIONS', app.blinkLocate);
            save("blinkLocations.mat","app.blinkLocate","-mat");
            
        end

        % Menu selected function: SavecurrentmissionMenu
        function SavecurrentmissionMenuSelected(app, event)
            

        end

        % Menu selected function: RuntestsonmodelsMenu
        function RuntestsonmodelsMenuSelected(app, event)
            if size(app.signals,1) >= 102400/2
                app.testSignals = app.signals{1:10240/2,:};
            else
                app.testSignals = app.signals;
            end
            for name = app.ModelListDropDown.Items
                name = name{1}(2:end-1);
                h = waitbar(0, ['Testing model: ' name]);
                testDetector = blinkDetector(app.testSignals, app.samplingRate, name, app.options);
    
                app.testResult{end+1} = testDetector.detectBlinks(h);
    
                close(h);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {530, 530};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FunctionsMenu = uimenu(app.UIFigure);
            app.FunctionsMenu.Text = 'Functions';
            app.RuntestsonmodelsMenu = uimenu(app.FunctionsMenu);
            app.RuntestsonmodelsMenu.MenuSelectedFcn = createCallbackFcn(app, @RuntestsonmodelsMenuSelected, true);
            app.RuntestsonmodelsMenu.Text = 'Run tests on models';
            app.RejectblinksMenu = uimenu(app.FunctionsMenu);
            app.RejectblinksMenu.Text = 'Reject blinks';
            app.MultipledataMenu = uimenu(app.FunctionsMenu);
            app.MultipledataMenu.Text = 'Multiple data';
            app.LoadMenu = uimenu(app.UIFigure);
            app.LoadMenu.Text = 'Load';
            app.LoadfromALLEEGMenu = uimenu(app.LoadMenu);
            app.LoadfromALLEEGMenu.Text = 'Load from ALLEEG';
            app.LoadfromcsvfileMenu = uimenu(app.LoadMenu);
            app.LoadfromcsvfileMenu.Text = 'Load from .csv file';
            app.LoadsavedmissionMenu = uimenu(app.LoadMenu);
            app.LoadsavedmissionMenu.Text = 'Load saved mission ';
            app.SaveMenu = uimenu(app.UIFigure);
            app.SaveMenu.Text = 'Save';
            app.SavelocationsMenu = uimenu(app.SaveMenu);
            app.SavelocationsMenu.MenuSelectedFcn = createCallbackFcn(app, @SavelocationsMenuSelected, true);
            app.SavelocationsMenu.Text = 'Save locations';
            app.SavecurrentmissionMenu = uimenu(app.SaveMenu);
            app.SavecurrentmissionMenu.MenuSelectedFcn = createCallbackFcn(app, @SavecurrentmissionMenuSelected, true);
            app.SavecurrentmissionMenu.Text = 'Save current mission';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Label = uilabel(app.LeftPanel);
            app.SamplingRateperseccondLabel = uilabel(app.LeftPanel);
            app.SamplingRateperseccondLabel.Text = {'Sampling Rate'; '(per seccond)'};
            app.SamplingRateperseccondEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SamplingRateperseccondEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingRateperseccondEditFieldValueChanged, true);
            app.StartDetectionButton = uibutton(app.LeftPanel, 'push');
            app.StartDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @StartDetectionButtonPushed, true);
            app.StartDetectionButton.Text = 'Start Detection';
            app.ModelListDropDownLabel = uilabel(app.LeftPanel);
            app.ModelListDropDownLabel.Text = 'Model List';
            app.ModelListDropDown = uidropdown(app.LeftPanel);
            app.ModelListDropDown.Items = {'''TCNOC''', '''1d-CNNOC''', '''LSTMOC''', '''biLSTMOC'''};
            app.ModelListDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelListDropDownValueChanged, true);
            app.ModelListDropDown.Value = '''TCNOC''';
            app.DoubleVerificationLabel = uilabel(app.LeftPanel);
            app.DoubleVerificationLabel.Text = {'Double '; 'Verification'};
            app.DoubleVerificationListBox = uilistbox(app.LeftPanel);
            app.DoubleVerificationListBox.Items = {'1', '2', '3', '4', '5'};
            app.DoubleVerificationListBox.ValueChangedFcn = createCallbackFcn(app, @DoubleVerificationListBoxValueChanged, true);
            app.DoubleVerificationListBox.Value = '3';
            app.DisplayChannelDropDownLabel = uilabel(app.LeftPanel);
            app.DisplayChannelDropDownLabel.Text = 'Display Channel';
            app.DisplayChannelDropDown = uidropdown(app.LeftPanel);
            app.DisplayChannelDropDown.Items = {'1', '2', '3'};
            app.DisplayChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplayChannelDropDownValueChanged, true);
            app.DisplayChannelDropDown.Value = '1';
            app.RegionplotSwitchLabel = uilabel(app.LeftPanel);
            app.RegionplotSwitchLabel.Text = 'Region plot';
            app.RegionplotSwitch = uiswitch(app.LeftPanel, 'slider');
            app.RegionplotSwitch.ValueChangedFcn = createCallbackFcn(app, @RegionplotSwitchValueChanged, true);
            app.DirectindexSwitchLabel = uilabel(app.LeftPanel);
            app.DirectindexSwitchLabel.Text = 'Direct index';
            app.DirectindexSwitch = uiswitch(app.LeftPanel, 'slider');
            app.DirectindexSwitch.ValueChangedFcn = createCallbackFcn(app, @DirectindexSwitchValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'time')
            ylabel(app.UIAxes, 'V')
            app.UIAxes.XLimitMethod = 'tight';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simpleDisplay(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end